classdef Project1_Group_A1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UndoLastEffectButton           matlab.ui.control.Button
        SaveFileButton                 matlab.ui.control.Button
        PlaySoundButton                matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        FadeTab                        matlab.ui.container.Tab
        TypeofFadeEffectDropDown       matlab.ui.control.DropDown
        TypeofFadeEffectDropDownLabel  matlab.ui.control.Label
        ApplyEffectButton              matlab.ui.control.Button
        FadeLengthsEditField           matlab.ui.control.NumericEditField
        FadeLengthsEditFieldLabel      matlab.ui.control.Label
        FadeCurveDropDown              matlab.ui.control.DropDown
        FadeCurveDropDownLabel         matlab.ui.control.Label
        TremoloTab                     matlab.ui.container.Tab
        ApplyEffectButton_2            matlab.ui.control.Button
        TypeofWaveDropDown             matlab.ui.control.DropDown
        TypeofWaveDropDownLabel        matlab.ui.control.Label
        DepthofAmplitudeSlider         matlab.ui.control.Slider
        DepthofAmplitudeLabel          matlab.ui.control.Label
        FrequencySlider                matlab.ui.control.Slider
        FrequencySliderLabel           matlab.ui.control.Label
        RhythmicPanningTab             matlab.ui.container.Tab
        ApplyEffectButton_5            matlab.ui.control.Button
        FrequencySlider_2              matlab.ui.control.Slider
        FrequencySlider_2Label         matlab.ui.control.Label
        AmplitudeKnob                  matlab.ui.control.Knob
        Label                          matlab.ui.control.Label
        EchoTab                        matlab.ui.container.Tab
        EchoDelayDropDown              matlab.ui.control.DropDown
        EchoDelayDropDownLabel         matlab.ui.control.Label
        ApplyEffectButton_3            matlab.ui.control.Button
        ReverbTab                      matlab.ui.container.Tab
        ApplyEffectButton_4            matlab.ui.control.Button
        SelectFileButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename
        pathname
        y
        Fs
        Ts
        fullpathname
        freq
        player
        fadetime
        numOfSamples
        LinFadeIn
        LinFadeOut
        temp
        out
        datasound
        freqsam
        t
        N
        current
        previous
        undoFlag
    end
    methods (Access = private)
            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.current = 0;
            app.previous = 0;
            set(app.UndoLastEffectButton, 'enable', 'off')
            
            %For testing only
%             load handel
%             
%             app.y = y;
%             app.Fs = Fs;
%             app.Ts = 1/app.Fs;
%             app.N = length(app.y);
%             app.t = [0:app.N-1]*app.Ts; app.t = app.t(:);
%             app.current = app.y;
%             plot(app.UIAxes,app.t,app.current);

%                 app.Fs = 48000;
%                 app.Ts = (1/app.Fs);
%                 app.t = 0:app.Ts:3;
%                 app.t = app.t(:);
%                 f = 100; phi = 0;
%                 app.y = sin(2*pi*f.*app.t + phi);
%                 app.N = length(app.y);
%                 app.current = app.y;
%                 plot(app.UIAxes,app.t,app.current);
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
           % Select wav File from the PC
            [app.filename, app.pathname] = uigetfile('*.wav', 'Select a wave file');
            app.fullpathname=fullfile(app.pathname, app.filename);
            [app.y,app.Fs] = audioread(app.filename);
            
            set(app.UndoLastEffectButton, 'enable', 'off')

            app.Ts = 1/app.Fs;
            app.N = length(app.y);
            app.t = [0:app.N-1]*app.Ts; app.t = app.t(:);
            app.current = app.y;
            plot(app.UIAxes,app.t,app.current);
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
%    try 
%        [app.y,app.Fs] = audioread(app.fullpathname);
%    catch
%        return
%    end
%    app.freq= 2* app.Fs;
%    app.player = audioplayer(app.y,app.freq);
%    play(app.player)
sound(app.current,app.Fs)
%            app.Ts = 1/app.Fs;
%     t = 0:app.Ts:(length(app.y)*app.Ts)-app.Ts;
%     plot(app.UIAxes,t,app.y);
%     set(app.UndoLastEffectButton, 'enable', 'off')
        end

        % Callback function
        function SelecttheeffectDropDownValueChanged(app, event)
            value = app.SelecttheeffectDropDown.Value;
            if value == 0
                app.fadetime = app.FadeLengthsEditField;
                app.numOfSamples = fadeTime*app.fs;
                a = linspace(0,1,app.numOfSamples); a = a(:); %1 in linspace is the volume of fade in/out, do not change
                app.LinFadeIn = a;
                app.LinFadeOut = 1-a; % Equivalent = linspace(1,0,numOfSamples);
            end

        end

        % Value changed function: FadeCurveDropDown
        function FadeCurveDropDownValueChanged(app, event)
        value = app.FadeCurveDropDown.Value;
        disp(value)
        end

        % Value changed function: TypeofFadeEffectDropDown
        function TypeofFadeEffectDropDownValueChanged(app, event)

        end

        % Button pushed function: ApplyEffectButton_2
        function ApplyEffectButton_2Pushed(app, event)
            app.previous = app.current;
            set(app.UndoLastEffectButton, 'enable', 'on')
            % Tremolo parameters;
            depth = app.DepthofAmplitudeSlider.Value; %user definable; intensity of effect: chamged amplitude and offset; ranges from [0,100]%
            f = app.FrequencySlider.Value; %user definable; frequency of effect; ranges from [0.1,20] Hz
            waveType = app.TypeofWaveDropDown.Value; %user definable; select between sine, square, triange, sawtooth
            phi = 0; %set by user? is this needed?

            amp = 0.5*(depth/100);
            offset = 1 - amp;
            
            %select wavetype
            if strcmpi(waveType, 'Sine')
                sw = sin(2*pi*f.*app.t+phi); % sin wave
            elseif strcmpi(waveType, 'Square')
                sw = square(2*pi*f.*app.t+phi); % square wave
            elseif strcmpi(waveType, 'Triangle')
                sw = sawtooth(2*pi*f.*app.t+phi, 0.5); % triangle wave; set 'xmax' to 0.5 in sawtooth function
            else 
                sw = sawtooth(2*pi*f.*app.t+phi); % sawtooth wave
            end 
            
            mod = amp.*sw + offset;
            
            % Modulate the amplitude of the carrier by the modulator
            app.current = app.current.*mod;
            
            % Plot the output and listen to the result
            
            plot(app.UIAxes,app.t,app.current);

        end

        % Button pushed function: ApplyEffectButton_5
        function ApplyEffectButton_5Pushed(app, event)
             if width(app.current) > 1
                fig =uifigure;
                uialert(fig,'Cannot perform this effect on two-channel audio file','Error!')
                return
             end

            
            app.previous = app.current;
            set(app.UndoLastEffectButton, 'enable', 'on')
            % AUTOPANEXAMPLE
            % This script implements the automatic panning (auto–pan)
            % effect. The function "pan" is used to process an
            % input signal, along with an array of pan values
            % for each sample number in the signal
            %
            % See also PAN
            % Input Variables
            % panType : 1 = linear, 2 = sqRt, 3 = sine–law
            % panValue : (–100 to +100)
            
            % User inputted variables
            f = app.FrequencySlider_2.Value; % Frequency of panning LFO
            panAmp = app.AmplitudeKnob.Value; % Intensity of effect
            
            % Generate sine wave LFO based on f and t
            panValue = panAmp*sin(2*pi*f*app.t);
            panType = 2; % Start with panType = 2, but try 1,2,3
            
            % Run pan function
            % Convert pan value to a normalized scale
            panTransform = (panValue/200) + 0.5;
    
            % Conditional statements determining panType
            switch panType
                case 1
                    leftAmp = 1 - panTransform;
                    rightAmp = panTransform;
                case 2
                    leftAmp = sqrt(1 - panTransform);
                    rightAmp = sqrt(panTransform);
                case 3
                    leftAmp = sin((1 - panTransform) * (pi/2));
                    rightAmp = sin(panTransform * (pi/2));
            end
    
            leftChannel = leftAmp.*app.current;
            rightChannel = rightAmp.*app.current;
    
            app.current = [leftChannel,rightChannel];

            plot(app.UIAxes,app.t,app.current);
        end

        % Button pushed function: ApplyEffectButton
        function ApplyEffectButtonPushed(app, event)
             if app.FadeLengthsEditField.Value > app.t
                fig =uifigure;
                uialert(fig,'Inputted length has to be less than the entire duration of audio file','Error!')
                return
             end
            
            app.previous = app.current;
            set(app.UndoLastEffectButton, 'enable', 'on')
            % Performs fade audio effect on inputted audio waveform
            %
            % Input variables
            % in: input audio signal
            % Fs: sampling frequency
            % fadeTime: legnth of fade in seconds
            % curveType: 0 = linear, 1 = exponential
            % fadeType: 0 = fade-in, 1 = fade-out
            fadeTime = app.FadeLengthsEditField.Value;
            curveType = app.FadeCurveDropDown.Value;
            fadeType = app.TypeofFadeEffectDropDown.Value;

            fadeN = fadeTime*app.Fs; %number of effected samples for fade
            a = linspace(0,1,fadeN);
            a = a(:); %transpose
            x = 2;%exponent of curve; user definable?
        
            %linear or exponential fade?
            if strcmpi(curveType,'Linear')
                fadeIn = a;
                fadeOut = a-1;
            else 
                if strcmpi(curveType,'Exponential')
                fadeOut = 1 - a.^x;
        
                a = linspace(1,0,fadeN); a = a(:);
                fadeIn = 1 - a.^x;
                end
            end
            
            %fade in or out?
            if strcmpi(fadeType,'Fade In')
                app.current(1:fadeN,1) = fadeIn.*app.current(1:fadeN,1);
                
                if width(app.current) == 2 
                    app.current(1:fadeN,2) = fadeIn.*app.current(1:fadeN,2);
                end
            else 
                if strcmpi(fadeType,'Fade Out')
                app.current(end-fadeN+1:end,1) = fadeOut.*app.current(end-fadeN+1:end,1);
                
                if width(app.current) == 2
                    app.current(end-fadeN+1:end,2) = fadeOut.*app.current(end-fadeN+1:end,2);
                end
                end
            end

            plot(app.UIAxes,app.t,app.current)
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            [file,path] = uiputfile('*.wav', 'Save Wav File');
            if isnumeric(file)
               return;    %user canceled
            end
            app.filename = fullfile(path, file);
            audiowrite(app.filename,app.current,app.Fs);
        end

        % Button pushed function: ApplyEffectButton_3
        function ApplyEffectButton_3Pushed(app, event)
            app.previous = app.current;
            set(app.UndoLastEffectButton, 'enable', 'on')
            % ECHOFEEDBACK
            % This script demonstrates one example to create a
            % feedback, tempo–synchronized echo effect
            %
            % See also ECHOSYNC
            
            % Known tempo of recording
            beatsPerMin = 102; % units of beats per minute
            
            % Calculate beats per second
            beatsPerSec = beatsPerMin / 60; % 1 minute / 60 seconds
            
            % Calculate # of seconds per beat
            secPerBeat = 1/beatsPerSec;
            
            % Note division
            delay=app.EchoDelayDropDown.Value;

            if strcmpi(delay,'Whole')
                noteDiv = 4;
            elseif strcmpi(delay,'Half')
                noteDiv = 2;    
            elseif strcmpi(delay,'Quarter')
                noteDiv = 1;
            elseif strcmpi(delay,'Eighth')
                noteDiv = 0.5; 
            else
                noteDiv = 0.25;
            end

            % Calculate delay time in seconds
            timeSec = noteDiv * secPerBeat;
            
            % Convert to units of samples
            d = fix(timeSec * app.Fs); % round to nearest integer sample
            
            a = -0.75; % amplitude of delay branch
            
            % Index each element of our signal to create the output
            x = zeros(app.N,2);
            
            for n = 1:app.N
            % When the sample number is less than the time delay
            % Avoid indexing a negative sample number
                if n < d+1
                    % output = input
                    x(n,1) = app.current(n,1);
                    
                    if width(app.current) == 2
                        x(n,2) = app.current(n,2);
                    end        
                    % Now add in the delayed signal
                else
                    % output = input + delayed version of output
                    % reduce relative amplitude of delay to 3/4
                    x(n,1) = app.current(n,1) + (-a)*x(n-d,1);
                    
                    if width(app.current) == 2
                        x(n,2) = app.current(n,2) + (-a)*x(n-d,2);
                    end
                end
            end
            
            app.current = x;
            
            plot(app.UIAxes,app.t,app.current)
        end

        % Button pushed function: ApplyEffectButton_4
        function ApplyEffectButton_4Pushed(app, event)
            if width(app.current) > 1
                fig =uifigure;
                uialert(fig,'Cannot perform this effect on two-channel audio file','Error!')
                return
            end

            app.previous = app.current;
            set(app.UndoLastEffectButton, 'enable', 'on')
            % REVERBCONV
            % This script demonstrates the process to create
            % a stereo convolution reverb by using a two–channel
            % impulse response. This impulse response is based
            % on a measurement of a recording studio
            % in Nashville, Tennessee.
            
            % Import sound file and IR measurement
            [h] = audioread('reverbIR.wav'); % Stereo IR
            
            % Perform convolution
            Left = conv(app.current,h(:,1));
            Right = conv(app.current,h(:,2));
            
            app.current = [Left,Right];
            app.N = length(app.current);
            app.t = [0:app.N-1]*app.Ts; 
            app.t = app.t(:);

            plot(app.UIAxes,app.t,app.current)
        end

        % Callback function
        function waveEditFieldValueChanged(app, event)
            value = app.waveEditField.Value;
            app.waveEdit
        end

        % Button pushed function: UndoLastEffectButton
        function UndoLastEffectButtonPushed(app, event)
            app.current = 0;
            app.current = app.previous;
            set(app.UndoLastEffectButton, 'enable', 'off')
            plot(app.UIAxes,app.t,app.current)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio File Visualizer')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'sound';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FadeTab = uitab(app.TabGroup);
            app.FadeTab.Title = 'Fade';
            app.FadeCurveDropDownLabel = uilabel(app.FadeTab);
            app.FadeCurveDropDownLabel.Text = 'Fade Curve';
            app.FadeCurveDropDown = uidropdown(app.FadeTab);
            app.FadeCurveDropDown.Items = {'Linear', 'Exponential'};
            app.FadeCurveDropDown.ValueChangedFcn = createCallbackFcn(app, @FadeCurveDropDownValueChanged, true);
            app.FadeCurveDropDown.Value = 'Linear';
            app.FadeLengthsEditFieldLabel = uilabel(app.FadeTab);
            app.FadeLengthsEditFieldLabel.Text = 'Fade Length (s)';
            app.FadeLengthsEditField = uieditfield(app.FadeTab, 'numeric');
            app.ApplyEffectButton = uibutton(app.FadeTab, 'push');
            app.ApplyEffectButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyEffectButtonPushed, true);
            app.ApplyEffectButton.Text = 'Apply Effect';
            app.TypeofFadeEffectDropDownLabel = uilabel(app.FadeTab);
            app.TypeofFadeEffectDropDownLabel.Text = 'Type of Fade Effect';
            app.TypeofFadeEffectDropDown = uidropdown(app.FadeTab);
            app.TypeofFadeEffectDropDown.Items = {'Fade In', 'Fade Out'};
            app.TypeofFadeEffectDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofFadeEffectDropDownValueChanged, true);
            app.TypeofFadeEffectDropDown.Value = 'Fade In';
            app.TremoloTab = uitab(app.TabGroup);
            app.TremoloTab.Title = 'Tremolo';
            app.FrequencySliderLabel = uilabel(app.TremoloTab);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.TremoloTab);
            app.FrequencySlider.Limits = [0.1 20];
            app.FrequencySlider.Value = 0.1;
            app.DepthofAmplitudeLabel = uilabel(app.TremoloTab);
            app.DepthofAmplitudeLabel.Text = {'Depth of '; 'Amplitude'};
            app.DepthofAmplitudeSlider = uislider(app.TremoloTab);
            app.DepthofAmplitudeSlider.Value = 100;
            app.TypeofWaveDropDownLabel = uilabel(app.TremoloTab);
            app.TypeofWaveDropDownLabel.Text = 'Type of Wave';
            app.TypeofWaveDropDown = uidropdown(app.TremoloTab);
            app.TypeofWaveDropDown.Items = {'Sine', 'Square', 'Triangle', 'Sawtooth'};
            app.TypeofWaveDropDown.Value = 'Sine';
            app.ApplyEffectButton_2 = uibutton(app.TremoloTab, 'push');
            app.ApplyEffectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyEffectButton_2Pushed, true);
            app.ApplyEffectButton_2.Text = 'Apply Effect';
            app.RhythmicPanningTab = uitab(app.TabGroup);
            app.RhythmicPanningTab.Title = 'Rhythmic Panning';
            app.Label = uilabel(app.RhythmicPanningTab);
            app.Label.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.RhythmicPanningTab, 'continuous');
            app.AmplitudeKnob.Limits = [-100 100];
            app.FrequencySlider_2Label = uilabel(app.RhythmicPanningTab);
            app.FrequencySlider_2Label.Text = 'Frequency';
            app.FrequencySlider_2 = uislider(app.RhythmicPanningTab);
            app.FrequencySlider_2.Limits = [0.25 10];
            app.FrequencySlider_2.Value = 0.25;
            app.ApplyEffectButton_5 = uibutton(app.RhythmicPanningTab, 'push');
            app.ApplyEffectButton_5.ButtonPushedFcn = createCallbackFcn(app, @ApplyEffectButton_5Pushed, true);
            app.ApplyEffectButton_5.Text = 'Apply Effect';
            app.EchoTab = uitab(app.TabGroup);
            app.EchoTab.Title = 'Echo';
            app.ApplyEffectButton_3 = uibutton(app.EchoTab, 'push');
            app.ApplyEffectButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyEffectButton_3Pushed, true);
            app.ApplyEffectButton_3.Text = 'Apply Effect';
            app.EchoDelayDropDownLabel = uilabel(app.EchoTab);
            app.EchoDelayDropDownLabel.Text = 'Echo Delay';
            app.EchoDelayDropDown = uidropdown(app.EchoTab);
            app.EchoDelayDropDown.Items = {'Whole', 'Half', 'Quarter', 'Eighth', 'Sixteenth'};
            app.EchoDelayDropDown.Value = 'Whole';
            app.ReverbTab = uitab(app.TabGroup);
            app.ReverbTab.Title = 'Reverb';
            app.ApplyEffectButton_4 = uibutton(app.ReverbTab, 'push');
            app.ApplyEffectButton_4.ButtonPushedFcn = createCallbackFcn(app, @ApplyEffectButton_4Pushed, true);
            app.ApplyEffectButton_4.Text = 'Apply Effect';
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.SaveFileButton = uibutton(app.UIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.UndoLastEffectButton = uibutton(app.UIFigure, 'push');
            app.UndoLastEffectButton.ButtonPushedFcn = createCallbackFcn(app, @UndoLastEffectButtonPushed, true);
            app.UndoLastEffectButton.Text = 'Undo Last Effect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project1_Group_A1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end